classdef Pretrainer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        watercountlabel            matlab.ui.control.Label
        WaterulLabel               matlab.ui.control.Label
        RewnoLabel                 matlab.ui.control.Label
        rewcountlabel              matlab.ui.control.Label
        STOPButton                 matlab.ui.control.Button
        startauto                  matlab.ui.control.Button
        PretrainerinferfaceLabel   matlab.ui.control.Label
        AUTOMATICREWARDSTouseseparetlyfromthepretrainerLabel  matlab.ui.control.Label
        nextrewlabel               matlab.ui.control.Label
        NextrewardinsLabel         matlab.ui.control.Label
        sdSpinner                  matlab.ui.control.Spinner
        SpinnerLabel               matlab.ui.control.Label
        EverysSpinner              matlab.ui.control.Spinner
        EveryminLabel              matlab.ui.control.Label
        ElapsedtimeminLabel        matlab.ui.control.Label
        setnameimportantLabel      matlab.ui.control.Label
        TouseonlyafterpretrainerstartsLabel  matlab.ui.control.Label
        setButton                  matlab.ui.control.Button
        overallspeedlabel          matlab.ui.control.Label
        nrewards                   matlab.ui.control.Label
        OverallspeedLabel          matlab.ui.control.Label
        RewardsLabel               matlab.ui.control.Label
        STOPSAVEButton             matlab.ui.control.Button
        START                      matlab.ui.control.Button
        DateLabelValue             matlab.ui.control.Label
        IDEditField                matlab.ui.control.EditField
        IDEditFieldLabel           matlab.ui.control.Label
        waterupdate                matlab.ui.control.Button
        ETime                      matlab.ui.control.Label
        RewardLabel                matlab.ui.control.Label
        secondsontargetvalue       matlab.ui.control.Label
        SecondsontargetLabel       matlab.ui.control.Label
        avgspeedvalue              matlab.ui.control.Label
        AvgSpeedLabel              matlab.ui.control.Label
        ManualrewardButton         matlab.ui.control.Button
        lickupdate                 matlab.ui.control.Button
        AvgspeedcmsSpinner         matlab.ui.control.Spinner
        AvgspeedcmsSpinnerLabel    matlab.ui.control.Label
        TimerunningsSpinner        matlab.ui.control.Spinner
        TimerunningsSpinnerLabel   matlab.ui.control.Label
        LickporttshEditField       matlab.ui.control.NumericEditField
        LickporttshLabel           matlab.ui.control.Label
        WatersizemsEditField       matlab.ui.control.NumericEditField
        WatersizemsEditFieldLabel  matlab.ui.control.Label
        lickplot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
       speedcnt = 0;
       timereading = 0;
       stop=0;
       speedtsh
       timetsh
       rewsize 
       s
       id
       date
       pretrainer_runtime
       avgspeed
       all
       tsh_lick
       %timeinsess %reward %avgspeed %lick %now
       SubjM = [0 0 0 0];
       reward
       overall_speed 
       number_rewards
       automaticdeliverys
       automaticdeliverysd
       started
       autostop
       rewcounter = 0;
    end
    
    methods (Access = private)
        
        function startconection(app)
            app.s = serialport("COM4",9600);% can check the serialport number from device manager and change the name accordingly COM4
            flush(app.s);
            configureTerminator(app.s,"CR");
            fopen(app.s);
            app.speedtsh = app.AvgspeedcmsSpinner.Value;
            app.timetsh = app.TimerunningsSpinner.Value;
            app.tsh_lick = app.LickporttshEditField.Value;
            app.rewsize = app.WatersizemsEditField.Value;
            app.automaticdeliverys = app.EverysSpinner.Value;
            app.automaticdeliverysd = app.sdSpinner.Value;
            %fprintf(app.s,['thr ', num2str(app.tsh_lick)]); %% sets the treshold for the lickport
            %status = strtrim(fscanf(app.s));
            fprintf(app.s, 'START');
            app.started = 1;
        end
        
        function plots(app)
            if app.SubjM(end,3) == 1
                scatter(app.lickplot,app.SubjM(end,1),0.5,7,"blue","|");
                hold(app.lickplot,"on");
            end
            if app.SubjM(end,4) == 1
                scatter(app.lickplot,app.SubjM(end,1),0.5,30,'green',"V")
                hold(app.lickplot,"on");
            end
        end
        
        function log(app)
            idt = string(app.id);
            datet = string(app.date);
            overall_speedt = string(app.overall_speed);
            n_rewards = string(app.number_rewards);
            pretraining_time = string(app.pretrainer_runtime);
            total_water = string(app.number_rewards*3.5);
            data = table(idt,datet,overall_speedt,n_rewards,total_water,pretraining_time);
            filename = 'pretrainerlog/log.xlsx';
            if isfile(filename)
                % Read the existing data from the Excel file
                existingData = readtable(filename);
                % Append the new data
                updatedData = [existingData; data];
            else
                % If the file does not exist, use the new data directly
                updatedData = data;
            end
            nottest =  ~strcmp(idt,"test");
            if nottest==1
                writetable(updatedData,filename);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startconection(app)
            app.date = datestr(floor(now), 'yyyy_mm_dd');
            app.DateLabelValue.Text = app.date;
            addpath('.\pretrainerlog')
        end

        % Button pushed function: lickupdate
        function lickupdatePushed(app, event)
            app.tsh_lick = app.LickporttshEditField.Value;
            fprintf(app.s,['thr ', num2str(app.tsh_lick)]); %% sets the treshold for the lickport
        end

        % Value changed function: TimerunningsSpinner
        function TimerunningsSpinnerValueChanged(app, event)
            app.timetsh  = app.TimerunningsSpinner.Value;
        end

        % Value changed function: AvgspeedcmsSpinner
        function AvgspeedcmsSpinnerValueChanged(app, event)
            app.speedtsh = app.AvgspeedcmsSpinner.Value;
        end

        % Button pushed function: ManualrewardButton
        function ManualrewardButtonPushed(app, event)
            fprintf(app.s,['SOL ', num2str(1), ' ', num2str(app.rewsize)]);
            app.RewardLabel.Text = "Reward!";
            pause(1/1000);
            app.RewardLabel.Text = "";
        end

        % Button pushed function: waterupdate
        function waterupdateButtonPushed(app, event)
            app.rewsize = app.WatersizemsEditField.Value;
        end

        % Button pushed function: START
        function STARTPushed(app, event)
            if app.started ~= 1
                startconection(app)
            end
            app.all = [];
            app.speedtsh = app.AvgspeedcmsSpinner.Value;
            app.timetsh = app.TimerunningsSpinner.Value;
            app.tsh_lick = app.LickporttshEditField.Value;
            app.rewsize = app.WatersizemsEditField.Value;
            j=0;
            t0 = tic;
            while 1
                if app.stop == 1
                    app.overall_speed = mean(app.SubjM(:,2));
                    app.number_rewards = sum(app.SubjM(:,4));
                    app.pretrainer_runtime = 0;
                    app.ETime.Text = num2str(app.pretrainer_runtime);
                    app.SubjM = [0 0 0 0];
                    clear app.s 
                    app.started = 0;
                    log(app)
                    break;
                end
                flush(app.s);
                fprintf(app.s,'SEND');
                status = strtrim(fscanf(app.s));
                data = sscanf(status,'%f,',[1 4]);
                j = j+1;
                if j>1
                    distance = data(1,2);
                    speed = data(1,3)/10; %in cm/s
                    if speed < 0
                        speed = 0;
                    end
                    licks = data(1,4);
                    app.reward = 0;
                    app.all = [app.all; [distance speed licks]];
                   if size(app.all,1)==1
                        app.avgspeed = app.all(end,2);
                        app.avgspeedvalue.Text = num2str(round(app.avgspeed,1));
                   else
                        app.avgspeed = (app.all(end-1,2)+app.all(end,2))/2;
                        app.avgspeedvalue.Text = num2str(round(app.avgspeed,1));
                        
                   end
                   if app.avgspeed>=app.speedtsh
                      if app.speedcnt == 0
                          timeunderspeed = tic;
                          app.speedcnt = app.speedcnt + 1;
                      else
                          app.RewardLabel.Text = "";
                          app.timereading = toc(timeunderspeed);
                          app.secondsontargetvalue.Text = num2str(round(app.timereading,1));
                          if app.timereading >= app.timetsh
                             fprintf(app.s,['SOL ', num2str(1), ' ', num2str(app.rewsize)]);
                             app.RewardLabel.Text = "Reward!";
                             pause(1/1000);
                             app.reward = 1;
                             app.speedcnt = 0;
                             app.timereading = 0;
                          end
                      end
                   else
                       app.speedcnt = 0;
                       app.RewardLabel.Text = "";
                       app.secondsontargetvalue.Text = num2str(0);
                   end
                end
                app.pretrainer_runtime = round(toc(t0)/60,2);
                app.ETime.Text = num2str(app.pretrainer_runtime);
                app.overall_speed = mean(app.SubjM(:,2));
                app.number_rewards = sum(app.SubjM(:,4));
                app.overallspeedlabel.Text= num2str(app.overall_speed);
                app.nrewards.Text = num2str(app.number_rewards);
                if j>3
                    app.SubjM(end+1,:) = [app.pretrainer_runtime, app.avgspeed, licks, app.reward];
                    plots(app)
                end
            end
        end

        % Button pushed function: STOPSAVEButton
        function STOPSAVEButtonPushed(app, event)
            app.stop = 1;
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            app.id = app.IDEditField.Value;
        end

        % Value changed function: EverysSpinner
        function EverysSpinnerValueChanged(app, event)
            value = app.EverysSpinner.Value;
            app.automaticdeliverys = value;
        end

        % Value changed function: sdSpinner
        function sdSpinnerValueChanged(app, event)
            value = app.sdSpinner.Value;
            app.automaticdeliverysd = value;
        end

        % Button pushed function: startauto
        function startautoButtonPushed(app, event)
            fprintf(app.s,['SOL ', num2str(1), ' ', num2str(app.rewsize)]);
            app.autostop=0;
            app.RewardLabel.Text = "Reward!";
            pause(1/100);
            app.RewardLabel.Text = "";
            delivered = 1;
            app.rewsize = app.WatersizemsEditField.Value;
            app.automaticdeliverys = app.EverysSpinner.Value;
            app.automaticdeliverysd = app.sdSpinner.Value;
            t0_auto = tic;
            rew = 0;
            j = 0;
            while 1
                if app.autostop==1
                    break;
                end
                flush(app.s);
                fprintf(app.s,'SEND');
                status = strtrim(fscanf(app.s));
                data = sscanf(status,'%f,',[1 4]);
                j = j+1;
                if j>1
                    licks = data(1,4);
                    rew = 0;
                end
                if delivered == 1
                    deliverytime = tic;
                    up = app.automaticdeliverys+app.automaticdeliverysd;
                    low = app.automaticdeliverys-app.automaticdeliverysd;
                    r = (up-low).*rand(1,1) + low;
                    delivered = 0;
                else
                   time_since_delivery = toc(deliverytime);
                   time_to_deliver = round(r,2);
                   reminder = time_to_deliver - time_since_delivery;
                   app.nextrewlabel.Text = num2str(reminder);
                   pause(1/100);
                   if reminder <= 0
                       fprintf(app.s,['SOL ', num2str(1), ' ', num2str(app.rewsize)]);
                        app.rewcounter = app.rewcounter + 1;
                        rew = 1;
                        app.rewcountlabel.Text = num2str(app.rewcounter);
                        pause(1/1000);
                        app.watercountlabel.Text = num2str(app.rewcounter*3.5);
                        pause(1/1000);
                        app.RewardLabel.Text = "Reward!";
                        pause(1/10);
                        app.RewardLabel.Text = "";
                        delivered = 1; 
                   end
                   runtime = toc(t0_auto);
                    if licks == 1
                        scatter(app.lickplot,runtime,0.5,7,"blue","|");
                        hold(app.lickplot,"on");
                    end
                    if rew == 1
                        scatter(app.lickplot,runtime,0.5,30,'green',"V")
                        hold(app.lickplot,"on");
                    end
                end
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.autostop = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lickplot = uiaxes(app.UIFigure);
            xlabel(app.lickplot, 'Time')
            zlabel(app.lickplot, 'Z')
            app.lickplot.YTick = [0 0.5 1];
            app.lickplot.YTickLabel = {'0'; 'lick/reward'; '1'};
            app.WatersizemsEditFieldLabel = uilabel(app.UIFigure);
            app.WatersizemsEditFieldLabel.Text = 'Water size (ms)';
            app.WatersizemsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WatersizemsEditField.Value = 195;
            app.LickporttshLabel = uilabel(app.UIFigure);
            app.LickporttshLabel.Text = 'Lickport tsh';
            app.LickporttshEditField = uieditfield(app.UIFigure, 'numeric');
            app.LickporttshEditField.Value = 20;
            app.TimerunningsSpinnerLabel = uilabel(app.UIFigure);
            app.TimerunningsSpinnerLabel.Text = 'Time running (s)';
            app.TimerunningsSpinner = uispinner(app.UIFigure);
            app.TimerunningsSpinner.ValueChangedFcn = createCallbackFcn(app, @TimerunningsSpinnerValueChanged, true);
            app.TimerunningsSpinner.Value = 3;
            app.AvgspeedcmsSpinnerLabel = uilabel(app.UIFigure);
            app.AvgspeedcmsSpinnerLabel.Text = 'Avg. speed (cm/s)';
            app.AvgspeedcmsSpinner = uispinner(app.UIFigure);
            app.AvgspeedcmsSpinner.ValueChangedFcn = createCallbackFcn(app, @AvgspeedcmsSpinnerValueChanged, true);
            app.AvgspeedcmsSpinner.Value = 25;
            app.lickupdate = uibutton(app.UIFigure, 'push');
            app.lickupdate.ButtonPushedFcn = createCallbackFcn(app, @lickupdatePushed, true);
            app.lickupdate.Text = {'Update'; 'lickport tresh'};
            app.ManualrewardButton = uibutton(app.UIFigure, 'push');
            app.ManualrewardButton.ButtonPushedFcn = createCallbackFcn(app, @ManualrewardButtonPushed, true);
            app.ManualrewardButton.Text = 'Manual reward';
            app.AvgSpeedLabel = uilabel(app.UIFigure);
            app.AvgSpeedLabel.Text = 'Avg. Speed:';
            app.avgspeedvalue = uilabel(app.UIFigure);
            app.avgspeedvalue.Text = '0';
            app.SecondsontargetLabel = uilabel(app.UIFigure);
            app.SecondsontargetLabel.Text = 'Seconds on target:';
            app.secondsontargetvalue = uilabel(app.UIFigure);
            app.secondsontargetvalue.Text = '0';
            app.RewardLabel = uilabel(app.UIFigure);
            app.RewardLabel.Text = '';
            app.ETime = uilabel(app.UIFigure);
            app.ETime.Text = '0';
            app.waterupdate = uibutton(app.UIFigure, 'push');
            app.waterupdate.ButtonPushedFcn = createCallbackFcn(app, @waterupdateButtonPushed, true);
            app.waterupdate.Text = {'Update '; 'Water size'};
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'text');
            app.DateLabelValue = uilabel(app.UIFigure);
            app.DateLabelValue.Text = '';
            app.START = uibutton(app.UIFigure, 'push');
            app.START.ButtonPushedFcn = createCallbackFcn(app, @STARTPushed, true);
            app.START.Text = {'START'; 'Pretrainer'};
            app.STOPSAVEButton = uibutton(app.UIFigure, 'push');
            app.STOPSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @STOPSAVEButtonPushed, true);
            app.STOPSAVEButton.Text = 'STOP/SAVE';
            app.RewardsLabel = uilabel(app.UIFigure);
            app.RewardsLabel.Text = 'Rewards:';
            app.OverallspeedLabel = uilabel(app.UIFigure);
            app.OverallspeedLabel.Text = 'Overall speed:';
            app.nrewards = uilabel(app.UIFigure);
            app.nrewards.Text = '';
            app.overallspeedlabel = uilabel(app.UIFigure);
            app.overallspeedlabel.Text = '';
            app.setButton = uibutton(app.UIFigure, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.TouseonlyafterpretrainerstartsLabel = uilabel(app.UIFigure);
            app.TouseonlyafterpretrainerstartsLabel.Text = 'To use only after pretrainer starts:';
            app.setnameimportantLabel = uilabel(app.UIFigure);
            app.setnameimportantLabel.Text = '<-- set name (important)';
            app.ElapsedtimeminLabel = uilabel(app.UIFigure);
            app.ElapsedtimeminLabel.Text = 'Elapsed time (min)';
            app.EveryminLabel = uilabel(app.UIFigure);
            app.EveryminLabel.Text = 'Every (s)';
            app.EverysSpinner = uispinner(app.UIFigure);
            app.EverysSpinner.Limits = [1 Inf];
            app.EverysSpinner.ValueChangedFcn = createCallbackFcn(app, @EverysSpinnerValueChanged, true);
            app.EverysSpinner.Value = 60;
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = '+-';
            app.sdSpinner = uispinner(app.UIFigure);
            app.sdSpinner.Limits = [0 Inf];
            app.sdSpinner.ValueChangedFcn = createCallbackFcn(app, @sdSpinnerValueChanged, true);
            app.sdSpinner.Value = 10;
            app.NextrewardinsLabel = uilabel(app.UIFigure);
            app.NextrewardinsLabel.Text = 'Next reward in (s):';
            app.nextrewlabel = uilabel(app.UIFigure);
            app.nextrewlabel.Text = '';
            app.AUTOMATICREWARDSTouseseparetlyfromthepretrainerLabel = uilabel(app.UIFigure);
            app.AUTOMATICREWARDSTouseseparetlyfromthepretrainerLabel.Text = 'AUTOMATIC REWARDS (To use separetly from the pretrainer)';
            app.PretrainerinferfaceLabel = uilabel(app.UIFigure);
            app.PretrainerinferfaceLabel.Text = 'Pretrainer inferface';
            app.startauto = uibutton(app.UIFigure, 'push');
            app.startauto.ButtonPushedFcn = createCallbackFcn(app, @startautoButtonPushed, true);
            app.startauto.Text = {'START'; 'Auto'};
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.rewcountlabel = uilabel(app.UIFigure);
            app.rewcountlabel.Text = '0';
            app.RewnoLabel = uilabel(app.UIFigure);
            app.RewnoLabel.Text = 'Rew no.';
            app.WaterulLabel = uilabel(app.UIFigure);
            app.WaterulLabel.Text = 'Water (ul)';
            app.watercountlabel = uilabel(app.UIFigure);
            app.watercountlabel.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pretrainer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end